classdef IRapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NameEditField                 matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        SavematButton                 matlab.ui.control.Button
        DrawROIsPanel                 matlab.ui.container.Panel
        DualdrawCheckBox              matlab.ui.control.CheckBox
        BothimagesCheckBox            matlab.ui.control.CheckBox
        ROIsListBox                   matlab.ui.control.ListBox
        ROIsListBoxLabel              matlab.ui.control.Label
        NumberofROIsEditField         matlab.ui.control.NumericEditField
        NumberofROIsEditFieldLabel    matlab.ui.control.Label
        AddROIsButton                 matlab.ui.control.Button
        AnalysisPanel                 matlab.ui.container.Panel
        ApplytoallCheckBox            matlab.ui.control.CheckBox
        ArtefactsButton               matlab.ui.control.Button
        lpassCheckBox                 matlab.ui.control.CheckBox
        SpectralbandsButton           matlab.ui.control.Button
        ThermalanalysisButton         matlab.ui.control.Button
        WaveletsButton                matlab.ui.control.Button
        SpectralflowButton            matlab.ui.control.Button
        MeanstdminmaxButton           matlab.ui.control.Button
        GammaindexButton              matlab.ui.control.Button
        SubtractButton                matlab.ui.control.Button
        ComputePanel                  matlab.ui.container.Panel
        ColormapresolutionKEditField  matlab.ui.control.NumericEditField
        ColormapresolutionKEditFieldLabel  matlab.ui.control.Label
        ColormapDropDown              matlab.ui.control.DropDown
        ColormapDropDownLabel         matlab.ui.control.Label
        ShowvidCheckBox               matlab.ui.control.CheckBox
        ComputeRGBButton              matlab.ui.control.Button
        ParametersPanel               matlab.ui.container.Panel
        FramestotLabel                matlab.ui.control.Label
        EndsframeLabel                matlab.ui.control.Label
        StartsframeLabel              matlab.ui.control.Label
        Fr9HzLabel                    matlab.ui.control.Label
        EndtimeSlider                 matlab.ui.control.Slider
        EndtimeSliderLabel            matlab.ui.control.Label
        StarttimeSlider               matlab.ui.control.Slider
        StarttimeSliderLabel          matlab.ui.control.Label
        PreprocessingtoolsPanel       matlab.ui.container.Panel
        InitTempCheckBox              matlab.ui.control.CheckBox
        BilateralCheckBox             matlab.ui.control.CheckBox
        AutoregisterButton            matlab.ui.control.Button
        NormalizetoareaButton         matlab.ui.control.Button
        CropButton                    matlab.ui.control.Button
        ReduceframesButton            matlab.ui.control.Button
        ImportdataButton              matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Copyright (C) 2022 Tomppa Pakarinen, tomppa.pakarinen@tuni.fi


    % This program is free software: you can redistribute it and/or modify it 
    % under the terms of the GNU General Public License as published by the  
    % Free Software Foundation, either version 3 of the License, or (at your 
    % option) any later version.
    %
    % This program is distributed in the hope that it will be useful, but 
    % WITHOUT ANY WARRANTY; without even the implied warranty of 
    % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the GNU General 
    % Public License for more details.
    % 
    % You should have received a copy of the GNU General Public License along 
    % with this program. If not, see http://www.gnu.org/licenses/
    
    properties (Access = private)
        
        seq; % Imported sequnce
        maxtemp; % Maximum temperature found in data
        mintemp; % Mininimum temperature found in data
        fs; % Framerate
        seqrange; % Sequence range
        imdata = struct; % Data structure to store image frames
        imdataorig % Original final structure
        y1; % Y dimension image size
        x1 % X dimension image size
        y2; % Y dimension image size
        x2 % X dimension image size
        datalength % Data length in milliseconds. Get total frames: app.datalength/1000*fs
        framecount;
        startframe;
        chosenROI % Array of ROI labels that are chosen from the listbox
        ROIvalues % Description
        endframe;
        RGBvid;
        RGBvid2;
        floatvid;
        floatvid2;
        diffI;
        meanend;
        meanstart;
        gamma = [];
        pos;
        dif;
        angiosomes = struct;
        axessize;
        results = struct;
        currentframe = '1';
        fuseim;
        imdata1 = struct;
        imdata2 = struct;
        initframe1;
        initframe2;
        initframe
        framesize;
        colormaprangevect;
        shortcmap;
        wt;
        f;
        coi;
        lastslider = 'Start';
        lastsliderevent;
        screensizex;
        screensizey;
        scrsize;
        ROI1pos;
        ROI2pos;
        passedband = [0.001 0.2]
        lowresim;
        RGBinitref;
        RGBinittarg;
        floatinitref;
        floatinittarg;
        progpath = addpath('C:\Users\Tomppa\Documents\MATLAB\IRanal 12.0 (uudempi)')
        savepath = "";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            %%
            
            
                 %
              
                [FILENAME, PATHNAME, ~] = uigetfile('*.csv;*.xlxs;*.mat;*.MAT;*.seq;*.csq', 'Choose single file for .mat, or any file in the directory for .csv');
                [~, ~, fExt] = fileparts(FILENAME);
                disp(['File extension: ', fExt]);
                switch lower(fExt)
                  case '.mat'
                    % Read .mat data. Must be a structure with fields,
                    % frame 1, frame 2, frame 3...
                    prompt = {'Sampling frequency', 'Temperature range [min, max]'};
                    dlgtitle = 'Set import parameters';
                    definput = {'9', '[18,35]'};
                    dims = [1 30];
                    newline = inputdlg(prompt,dlgtitle,dims,definput);
                    app.fs = str2double(newline{1});
                    dat = load([PATHNAME, FILENAME]);
                    app.imdata = dat.imdata;
                    asd = app.imdata;
                    save('testivar.mat', 'asd' );
                    minmaxtemp = eval(cell2mat(newline(2)));
                    app.mintemp = minmaxtemp(1);
                    app.maxtemp = minmaxtemp(2);
                    % app.seq, app.maxtemp, app.mintemp, app.fs, app.seqrange, app.imdata, app.datalength,
                    disp('Data imported');
                  case '.csv'
                    
                    % Read csv data (see FLIR tools+ output) 
                    
                    prompt = {'Frame delimiter', 'Sampling frequency [fps]', 'Framesize [hor., vert.]', 'delimiter','Number of files (depending on memory, parsing may be required)', 'Temp. range [min, max]'};
                    dlgtitle = 'Set import parameters';
                    definput = {'Frame', '9', '[240, 320]', ';', '1', '[18 35]'};
                    dims = [1 180];
                    newline = inputdlg(prompt,dlgtitle,dims,definput);
 
                    skipcondition = newline{1};
                    app.fs = str2double(newline{2});
                    app.framesize = eval(cell2mat(newline(3)));
                    structcount = str2double(newline{5});
                    delimiter = newline{4};
                    minmaxtemp = eval(cell2mat(newline(6)));
                    app.mintemp = minmaxtemp(1);
                    app.maxtemp = minmaxtemp(2);
                    % Camera frame size
                    tic
                    
                    app.imdata = readcsvdata(PATHNAME, 1, skipcondition, delimiter, 1, inf, app.fs, app.framesize,1);


                  case '.seq'
                    msgbox('FLIR atlas SDK and the proper Import function missing. Please use other import options or bundle required import SDKs');
                    disp('FLIR atlas SDK and the proper Import function missing. Please use other import options or bundle required import SDKs');
                    return
                  case '.csq'
                    msgbox('FLIR atlas SDK and the proper Import function missing. Please use other import options or bundle required import SDKs');
                    disp('FLIR atlas SDK and the proper Import function missing. Please use other import options or bundle required import SDKs');
                    return
                        
                  otherwise 
                    error('Unexpected file extension: %s', fExt);
                    return
                end
            
              
                % Define range vector for the colormap
                figure(app.UIFigure);
                app.colormaprangevect = floor(app.mintemp):app.ColormapresolutionKEditField.Value:ceil(app.maxtemp);
                % Use default colormap to compute shorter colormap
                app.shortcmap = computeNewColorMap(app.colormaprangevect, app.ColormapDropDown.Value);
                
                % Find screen size and video scale and set parameters for presentation
                app.scrsize = get(0,'screensize');
                app.screensizex = app.scrsize(3)/4; 
                app.screensizey = app.scrsize(4)/4;
                
                app.imdataorig = app.imdata;
                
                app.framecount = length(fieldnames(app.imdata));
                [app.y1,app.x1] = size(app.imdata.frame1);
                
                % Set slider limits to data length in seconds
                app.StarttimeSlider.Limits = [0, round(app.framecount/app.fs)];
                app.EndtimeSlider.Limits =  [0, round(app.framecount/app.fs)];
                
                % Show the first frame with colormap
                %dispim = uint8(app.imdata.(['frame', app.currentframe])*5);
                minV = min(min(app.imdata.(['frame', app.currentframe])));
                maxV = max(max(app.imdata.(['frame', app.currentframe])));
                dispim = uint8(floor((app.imdata.(['frame', app.currentframe]) - minV) / (maxV-minV) * 255));
                imshow(dispim, 'Parent', app.UIAxes);
                colormap(app.UIAxes, app.ColormapDropDown.Value);
                % refocus
                app.Fr9HzLabel.Text = ['fr:', num2str(app.fs), 'Hz'];
                app.StartsframeLabel.Text = ['Start [s/frame]: ', '0', '/', '1'];
                app.startframe = 1;
                app.EndsframeLabel.Text = ['End [s/frame]: ', num2str(app.framecount/app.fs), '/', num2str(app.framecount)];
                app.endframe = app.framecount;
                app.StarttimeSlider.Value = 0;
                app.EndtimeSlider.Value = app.EndtimeSlider.Limits(end);
                app.FramestotLabel.Text = ['Frames tot: ', num2str(length(fieldnames(app.imdata)))];


        end

        % Button pushed function: ReduceframesButton
        function ReduceframesButtonPushed(app, event)

            imdatatemp = struct;
            
            app.framecount = 1;
            prompt = {'Skipped frames:', 'Average frames? (0 or 1)'};
            dlgtitle = ['Set number of skipped frames (divisor of fs). Camera framerate is ', num2str(app.fs), 'fps'];
            definput = {'', '1'};
            dims = [1 80];
            newline = inputdlg(prompt,dlgtitle,dims,definput);
            % check if cancel is pressed
            if ~isempty(newline)
                skipnumber = cell2mat(newline(1));
                averages = newline{2};
            else
                skipnumber = 1;
                averages = '1';
            end
            skipnumber = str2double(skipnumber);
            lenfields = length(fieldnames(app.imdata));
            for i = 1:skipnumber:lenfields
                
                imdatatemp.(['frame', num2str(app.framecount)]) = app.imdata.(['frame', num2str(i)]);
                if averages == '1'
                    j = i+1;
                    while j < i+skipnumber
                        if j > lenfields
                            break
                        else
                            try
                               imdatatemp.(['frame', num2str(app.framecount)]) = imdatatemp.(['frame', num2str(app.framecount)]) + app.imdata.(['frame', num2str(j)]);
                            catch
                               disp('overflow'); 
                            end
                            j = j+1;
                        end
                    end
                    imdatatemp.(['frame', num2str(app.framecount)]) = imdatatemp.(['frame', num2str(app.framecount)]) / (j-i);
                    app.framecount = app.framecount+1;
                end
            end
            
            app.imdata = [];
            app.imdata = imdatatemp;
            app.imdataorig = app.imdata;
            app.FramestotLabel.Text = ['Frames tot: ', num2str(length(fieldnames(app.imdata)))];
            % Change framrate
            app.fs = app.fs/skipnumber;
            % Update framerate
            app.Fr9HzLabel.Text = ['fr:', num2str(app.fs), 'Hz'];
            app.endframe = floor(app.endframe/skipnumber);
            app.startframe = ceil(app.startframe/skipnumber);
            if app.endframe > app.framecount
                app.endframe = app.framecount;
            end
            
            % Set slider limits to data length in seconds
            app.StarttimeSlider.Limits = [0, round(app.endframe/app.fs)];
            app.EndtimeSlider.Limits =  [0, round(app.endframe/app.fs)];
            
            tempcurframe = str2double(app.currentframe);
            app.currentframe = num2str(round(tempcurframe/skipnumber));
            app.StartsframeLabel.Text = ['Start [s/frame]: ', num2str(app.startframe/app.fs),'/',num2str(app.startframe)];
            app.EndsframeLabel.Text = [ 'End [s/frame]: ', num2str(app.endframe/app.fs),'/',num2str(app.endframe)];
  
        end

        % Value changing function: StarttimeSlider
        function StarttimeSliderValueChanging(app, event)

            changingValue = event.Value;
            app.currentframe = num2str(ceil(changingValue*app.fs));
            totlen = length(fieldnames(app.imdata));
            if app.currentframe == '0'
                app.currentframe = '1';
            elseif app.currentframe >= totlen
                app.currentframe = num2str(totlen);
            end
            targsizecomputed = 0;
            refsizecomputed = 0;
            minV = app.mintemp;
            maxV = app.maxtemp;
            dispim = floor((app.imdata.(['frame', app.currentframe]) - minV) / (maxV-minV) * 255);
            
            
            % Mask the ROI areas
%             if ~isempty(app.ROIvalues)
%                 for i = 1:length(app.ROIvalues)
%                     tempROI = app.angiosomes.(app.ROIvalues{i});
%                     % Get sizes
%                     [rowS, colS] = size(tempROI);
%                     % Add the startingpoint
%                     if contains(app.ROIvalues{i}, "_targ")
%                         if targsizecomputed == 0
%                             row1 = floor(app.ROI2pos(2));
%                             column1 = floor(app.ROI2pos(1));
%                             targsizecomputed = 1;
%                         end
%                         % Flip 2nd ROI (since it is already flipped for registration)
%                         tempROI = flip(imcomplement(tempROI), 2);
%                     else
%                         if refsizecomputed == 0
%                             row1 = floor(app.ROI1pos(2));
%                             column1 = floor(app.ROI1pos(1));
%                             refsizecomputed = 1;
%                         end
%                         tempROI = imcomplement(tempROI);
%                     end
%                     % Determine lower right location.
%                     row2 = row1 + rowS - 1;
%                     column2 = column1 + colS - 1;
%                     dispim(row1:row2, column1:column2) = dispim(row1:row2, column1:column2).*tempROI;
%                 end
%             end
            imshow(uint8(dispim), 'Parent', app.UIAxes);
            colormap(app.UIAxes, app.ColormapDropDown.Value);
            % Change starttime text field
          
            app.StartsframeLabel.Text = ['Start [s/frame]: ', num2str(round(changingValue,2)),'/',app.currentframe];
            app.startframe = str2double(app.currentframe);
            app.lastsliderevent = event;
            
        end

        % Value changing function: EndtimeSlider
        function EndtimeSliderValueChanging(app, event)
            tic
            changingValue = event.Value;
            % Show the first frame
            totlen = length(fieldnames(app.imdata));
            app.currentframe = num2str(floor(changingValue*app.fs));
            if app.currentframe == '0'
                app.currentframe = '1';
                disp('First frame at frame 1');
            elseif str2double(app.currentframe) >= totlen
                app.currentframe = num2str(app.framecount);
                disp('Last frame set to the end');
            end
           
            minV = app.mintemp;
            maxV = app.maxtemp;
            app.currentframe
            dispim = floor((app.imdata.(['frame', app.currentframe]) - minV) / (maxV-minV) * 255);
            % Mask the ROI areas
%             if ~isempty(app.ROIvalues)
%                 for i = 1:length(app.ROIvalues)
%                     tempROI = app.angiosomes.(app.ROIvalues{i});
%                     % Get sizes
%                     [rowS, colS] = size(tempROI);
%                     % Add the startingpoint
%                     if contains(app.ROIvalues{i}, "_t")
%                         row1 = floor(app.ROI2pos(2));
%                         column1 = floor(app.ROI2pos(1));
%                         % Flip 2nd ROI (since it is already flipped for registration)
%                         tempROI = flip(imcomplement(tempROI), 2);
%                     else
%                         row1 = floor(app.ROI1pos(2));
%                         column1 = floor(app.ROI1pos(1));
%                         tempROI = imcomplement(tempROI);
%                     end
%                     % Determine lower right location.
%                     row2 = row1 + rowS - 1;
%                     column2 = column1 + colS - 1;
%                     dispim(row1:row2, column1:column2) = dispim(row1:row2, column1:column2).*tempROI;
%                 end
%             end
            dispim = uint8(dispim);
            imshow(dispim, 'Parent', app.UIAxes);
            colormap(app.UIAxes, app.ColormapDropDown.Value);
            
            % Change starttime text field
            app.EndsframeLabel.Text = ['End [s/frame]: ', num2str(round(changingValue,2)), '/', app.currentframe];
            app.endframe = str2double(app.currentframe);
            app.lastsliderevent = event;
            toc
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            disp("Cropping started. Crops the original image dataset. Not recommended, when image registration is used.");
            [~, area] = imcrop(uint8(app.imdata.(['frame', num2str(app.startframe)]))*5);
            xx = ceil(area(1)):(floor((area(3))+floor(area(1))));
            yy = ceil(area(2)):(floor((area(4))+floor(area(2))));
            for i = app.startframe:app.endframe
                  app.imdata.(['frame', num2str(i)]) = app.imdata.(['frame', num2str(i)])(yy,xx);
            end
        end

        % Button pushed function: AutoregisterButton
        function AutoregisterButtonPushed(app, event)
            addpath('C:\Users\Tomppa\Documents\MATLAB\IRanal 12.0 (uudempi)\ecc')
            % Data preparation
            
            prompt = {'Modality: ', 'Iterations: ', 'Method: ', 'Update reference [bool]', 'Update frequency [1/frames]', ...
                'Normalize to area', 'rot/frame constraint [degrees]', 'translation/frame contstraint [pixels]'};
            dlgtitle = 'Set mono/multimodal registration, number of iterations and registration method (rigid)';
            definput = {'monomodal', '10', 'rigid', '0', '500', '0', '15', '40'};
            dims = [1 90];
            newline = inputdlg(prompt,dlgtitle,dims,definput);
            
            if newline{6} == '1'
                if app.BilateralCheckBox.Value == 1
                    regdata1 = normalizeRegImages(app.imdata, app.screensizex, app.screensizey, app.scrsize);
                    regdata2 = normalizeRegImages(app.imdata, app.screensizex, app.screensizey, app.scrsize);
                else
                    regdata1 = normalizeRegImages(app.imdata, app.screensizex, app.screensizey, app.scrsize);
                end
            else
                regdata1 = app.imdata;
                regdata2 = app.imdata;
            end
           
            regmode = newline{1};
            numberofiterations = str2double(newline{2});
            disp(numberofiterations)
            method = newline{3};
            maxrot = str2double(newline{7})/180*pi;
            maxtrans = str2double(newline{8});
            % Register both feet if bilateral option is selected. First
            % registration so that possible cropping does not affect the
            % automatic registration

            
            fig1 = figure(1);
            hold on;
            fig1.Position = [app.screensizex app.screensizey app.scrsize(3)/1.5 app.scrsize(4)/1.5];
            if app.BilateralCheckBox.Value == 1
                rounds = 2;
                [~, area1] = imcrop(uint8(regdata1.(['frame', num2str(app.startframe)]))*5);
                [~, area2] = imcrop(uint8(regdata2.(['frame', num2str(app.startframe)]))*5);
                app.ROI1pos = area1;
                app.ROI2pos = area2;
            else
                rounds  = 1;
                [~, area1] = imcrop(uint8(regdata1.(['frame', num2str(app.startframe)]))*5);
                app.ROI1pos = area1;
            end
            hold off;
            close(figure(1));
            if isfield(app, 'imdata1')
                lendat = app.endframe - app.startframe;
            else
                lendat = app.endframe - app.startframe;
            end
            h = waitbar(0, 'Registering data...');
            for k = 1:rounds
                if k == 1
                    area  = area1;
                    regdata = regdata1;
                else
                    area = area2;
                    regdata = regdata2;
                end
                
                
                % Rectange coordinates
                app.(['x', num2str(k)]) = ceil(area(1)):(floor((area(3))+floor(area(1))));
                app.(['y', num2str(k)]) = ceil(area(2)):(floor((area(4))+floor(area(2))));
    
                % Define the reference image
                fixed1 = regdata.(['frame', num2str(app.startframe)])(app.(['y', num2str(k)]), app.(['x', num2str(k)]));
               
                
                % Register all images
                registered = struct;
    
                 % Split the reference image in 2 so both feet can be registered separately
                % Reference
                regframecount = 1;
    
                registered.frame1 = app.imdata.(['frame', num2str(app.startframe)])(app.(['y', num2str(k)]), app.(['x', num2str(k)]));
                [optimizer, metric] = imregconfig(regmode);
                levels = 2;
                prevtrans = [];
                 % Define the initial frame (if checkbox chosen)
                if app.InitTempCheckBox.Value == 1
                    itemp = regdata.(['frame', app.initframe{1}])(app.(['y', num2str(k)]), app.(['x', num2str(k)]));
                    transformthis = app.imdata.(['frame', app.initframe{1}])(app.(['y', num2str(k)]), app.(['x', num2str(k)]));
                    % Increase the number of iterations for the initial
                    % frame, since the temperature will change a lot
                    optimizer.MaximumIterations = 100;
                    Rmoving = imref2d(size(itemp));
                    Rfixed = imref2d(size(fixed1));
                    tform = imregtform(itemp, fixed1, method, optimizer, metric);
                    app.(['initframe', num2str(k)]) = imwarp(transformthis,Rmoving,tform,'OutputView',Rfixed, 'SmoothEdges', true);
                    %[~,~,app.(['initframe', num2str(k)])] = ecc(itemp, fixed1, levels, optimizer.MaximumIterations, 'affine');
                    
                end
                if numberofiterations > 0 && regframecount == 1
                    optimizer.MaximumIterations = numberofiterations;
                    if regmode == "monomodal"
                        optimizer.RelaxationFactor = 0.7; % increased from 0.5 default value because of noisy metric (E8)
                        optimizer.MaximumStepLength = 0.058; % Decreased from the default (0.0625)
                        optimizer.MinimumStepLength = 1e-6; % decreased from the default (1e-5)
                    end
                    %save('testmetric', 'metric');
                    %save('testoptimizer', 'optimizer');
                end
                % Go through all images
                for i = app.startframe:app.endframe
                    lastwarn = "";
                    % Define maximum translation and rotation
                    
                    moving1 = regdata.(['frame', num2str(i)])(app.(['y', num2str(k)]),app.(['x', num2str(k)]));
                    transformthis = app.imdata.(['frame', num2str(i)])(app.(['y', num2str(k)]),app.(['x', num2str(k)]));
                    %moving2 = imdata.(['frame', num2str(i)])(1:end,end/2:end);
                    if lastwarn == ""
                        if numberofiterations > 0
                            Rmoving = imref2d(size(moving1));
                            Rfixed = imref2d(size(fixed1));
                            if regframecount == 1
                                tform = imregtform(moving1, fixed1, method, optimizer, metric);
                            else
                                try
                                    tform = imregtform(moving1, fixed1, method, optimizer, metric, 'InitialTransformation', prevtrans);
                                catch
                                    disp('Error, transforming without initial transformation');
                                    tform = imregtform(moving1, fixed1, method, optimizer, metric);
                                end
                                    % Check if the rotation or translation is
                                % too large (aribitrary limits, which should be set by the user)
                                
                                % Get theta and x, y transfomations from tform: 
                                % 
                                % | cos(theta)  sin(theta)  0 |
                                % | -sin(theta) cos(theta)  0 |
                                % | cos(theta)  sin(theta)  0 |
                                % | trans(x)    trans(y)    1 |
                                theta = acos(tform.T(1,1)); % In radians
                                tx = tform.T(3,1);
                                ty = tform.T(3,2);
                                % Get theta from the previous tform
                                theta_prev = acos(prevtrans.T(1,1)); % In radians
                                tx_prev = prevtrans.T(3,1);
                                ty_prev = prevtrans.T(3,2);
                                % If rotation or translation exceed the
                                % bounds (=unrealistic movement) --> use
                                % the previous registration tform result
                                if abs(theta - theta_prev) > maxrot || abs(tx-tx_prev) > maxtrans || abs(ty - ty_prev) > maxtrans || contains(lastwarn, "Registration failed because optimization diverged. Try reducing the InitialRadius property of the optimizer.") 
                                    tform = prevtrans;
                                    if k == 1
                                       disp(['Transformation exceeded the rotational or translational boundaries. (ref. data: frame ', num2str(regframecount), ') Tform set to previous.' ...
                                        ' tx: ', num2str(tx), ' tx_prev: ', num2str(tx_prev), ' ty: ' num2str(ty), ' theta: ', num2str(theta), ' theta_prev: ', num2str(theta_prev)]);
                                    else
                                        disp(['Transformation exceeded the rotational or translational boundaries. (targ. data: frame ', num2str(regframecount), ') Tform set to previous.' ...
                                        ' tx: ', num2str(tx), ' tx_prev: ', num2str(tx_prev), ' ty: ' num2str(ty), ' theta: ', num2str(theta), ' theta_prev: ', num2str(theta_prev)]);
                                    end
                                    lastwarn = "";
                                    
                                end
                               
                                
                            end
                            prevtrans = tform;
                            registered.(['frame',num2str(regframecount)]) = imwarp(transformthis,Rmoving,tform,'OutputView',Rfixed, 'SmoothEdges', true);
                            %[~,~,registered.(['frame',num2str(regframecount)])] = ecc(moving1, fixed1, levels, optimizer.MaximumIterations, 'affine');
                            regframecount = regframecount + 1;
                        else
                            registered.(['frame',num2str(regframecount)]) = app.imdata.(['frame', num2str(i)])(app.(['y', num2str(k)]),app.(['x', num2str(k)]));
                            regframecount = regframecount + 1;
                        end
                        % Comment this for fixed reference frame
                        if newline{4} == '1'
                            if mod(regframecount, str2double(newline{5}))==0 && regframecount > 1
                                fixed1 = registered.(['frame',num2str(regframecount-1)]);
                            end
                        end
                        %
                    else
                        disp(lastwarn);
                    end
                    if mod(regframecount,70) || i == app.endframe
                        disp(['regframe count: ', num2str(regframecount + lendat*(k-1))])
                        disp(['tot: ', num2str(((app.endframe - app.startframe)*rounds))]);
                        waitbar((regframecount + lendat*(k-1))/((app.endframe - app.startframe)*rounds),h)
                    end
                end
                
                app.(['imdata', num2str(k)]) = registered;
                app.framecount = length(fieldnames(app.(['imdata', num2str(k)])));
            end
            delete(h);
            
            % Manual matching of the reference images
            % Input flipped image from the 2nd foot
            if rounds == 2
                if app.InitTempCheckBox.Value == 1
                    app.initframe2 = flip(app.initframe2,2);
                end
                
                registrationvectors = manualRegistration(app.imdata1.frame1, flip(app.imdata2.frame1,2));
                % Apply the tranformations to all frames for the target image
                % set (2nd ROI)
                h = waitbar(0, 'Transforming data...');
                datalen = length(fieldnames(app.imdata2));
                for i = 1:datalen
                    % Flip frame
                    app.imdata2.(['frame', num2str(i)]) = flip(app.imdata2.(['frame', num2str(i)]),2);
                    % Translate
                    app.imdata2.(['frame', num2str(i)]) = imtranslate(app.imdata2.(['frame', num2str(i)]), registrationvectors.translation);
                    % Rotate
                    app.imdata2.(['frame', num2str(i)]) = imrotate(app.imdata2.(['frame', num2str(i)]), registrationvectors.rotation, 'Crop');
                    if mod(i,50) || i == datalen
                        waitbar(i/datalen,h);
                    end
                end
                delete(h);
            end
            
        end

        % Button pushed function: ComputeRGBButton
        function ComputeRGBButtonPushed(app, event)
            %% Create 2D temp - RGB scale for image representation
            
            rangevect = floor(app.mintemp):app.ColormapresolutionKEditField.Value:ceil(app.maxtemp);
            videospeed = 0.000001; % Delay
            if isempty(fieldnames(app.imdata1))
                app.imdata1 = app.imdata;
            end
            
            videoOn = app.ShowvidCheckBox.Value;
            plotcurve = 0;
            dispEveryNthFrame = app.fs; % fps, max 9
            if app.BilateralCheckBox.Value == 0
                [app.RGBvid, ~, app.floatvid, ~] = playIrVideo(app.imdata1, app.imdataorig, rangevect, videospeed, 1, videoOn, plotcurve, dispEveryNthFrame, app.x1, app.y1, app.startframe, app.endframe, app.ColormapDropDown.Value);
                if app.InitTempCheckBox.Value == 1
                    % Must create a structure from the image for the IRvid
                    % function
                    initfs = struct;
                    initfs.frame1 = app.initframe1;
                    [app.RGBinitref, ~, app.floatinitref, ~] = playIrVideo(initfs, app.imdataorig, rangevect, videospeed, 1, 1, plotcurve, 1, app.x1, app.y1, 1, 1, app.ColormapDropDown.Value);
                end
            else
                [app.RGBvid, app.RGBvid2, app.floatvid, app.floatvid2] = playIrVideo(app.imdata1, app.imdata2, rangevect, videospeed, 1, videoOn, plotcurve, dispEveryNthFrame, app.x2, app.y2, 1, length(fieldnames(app.imdata1)), app.ColormapDropDown.Value);
                if app.InitTempCheckBox.Value == 1
                    initfs1 = struct;
                    initfs2 = struct;
                    initfs1.frame1 = app.initframe1;
                    initfs2.frame1 = app.initframe2;
                    [app.RGBinitref, app.RGBinittarg, app.floatinitref, app.floatinittarg] = playIrVideo(initfs1, initfs2, rangevect, videospeed, 1, 1, plotcurve, 1, app.x1, app.y1, 1, 1, app.ColormapDropDown.Value);
                end
            end
            
            if app.BilateralCheckBox.Value == 1
                app.BothimagesCheckBox.Visible = 1;
                app.DualdrawCheckBox.Visible = 1;
            end
        end

        % Button pushed function: NormalizetoareaButton
        function NormalizetoareaButtonPushed(app, event)
            % Save the original data
            app.imdataorig = app.imdata;
            maxtemp1 = max(max(app.imdata.frame1));

            normdata = struct;
            [~, areanorm] = imcrop(uint8(app.imdata.frame1./maxtemp1*255));
            set(gcf, 'Position', [app.screensizex app.screensizey app.scrsize(4) app.scrsize(3)]);
            close(figure(1));
        
            % Rectangle coordinates
            x22 = floor(areanorm(1)):(floor((areanorm(3))+floor(areanorm(1))));
            y22 = floor(areanorm(2)):(floor((areanorm(4))+floor(areanorm(2))));
            refmean = mean(app.imdata.frame1(y22,x22));
            normdata.frame1 = app.imdata.frame1;
            for i = 2:length(fieldnames(app.imdata))
               % Find the normalization constant of the current frame and normalize whole image to
               % reference background value
                framemean = mean(app.imdata.(['frame',num2str(i)])(y22,x22));
                normconst = refmean/framemean;
                % Normalize
                normdata.(['frame', num2str(i)]) = app.imdata.(['frame',num2str(i)])*normconst;
            end   
            app.imdata = normdata;
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
            
            % Subtraction image from the main figure display area
            lastframe = length(fieldnames(app.imdata));
            app.meanstart = (app.imdata.frame1 + app.imdata.frame2 + app.imdata.frame3 + app.imdata.frame4)/4;
            app.meanend = (app.imdata.(['frame', num2str(lastframe)]) + app.imdata.(['frame', num2str(lastframe-1)])...
                + app.imdata.(['frame', num2str(lastframe-2)]) + app.imdata.(['frame', num2str(lastframe-3)]))/4;
            app.diffI = app.meanstart - app.meanend;
            % Subtraction between registered images at slider start and end times
            
            % Subtraction image between reference frame and the first frame
            % at the slider start time
            %try
            if app.BilateralCheckBox.Value == 0
                substartendImRef = app.imdata1.(['frame', num2str(app.framecount)]) - app.imdata1.frame1;
                if app.InitTempCheckBox.Value == 1
                    % Subtraction image between reference frame and the last frame at the slider end time
                    subRefRefEnd = app.imdata1.(['frame', num2str(app.framecount)]) - app.initframe1;
                    subRefRefStart = app.imdata1.frame1  - app.initframe1;
                    app.results.subrefRefEnd = subRefRefEnd;
                    app.results.subrefRefStart = subRefRefStart;
                end
            else
                substartendImRef = app.imdata1.(['frame', num2str(app.framecount)]) - app.imdata1.frame1; 
                substartendImTarg = app.imdata2.(['frame', num2str(app.framecount)]) - app.imdata2.frame1; 
                app.results.subtractImageTarg = substartendImTarg;
                if app.InitTempCheckBox.Value == 1
                    % Subtraction image between reference frame and the last frame at the slider end time
                    subRefRefEnd = app.imdata1.(['frame', num2str(app.framecount)]) - app.initframe1;
                    subRefRefStart = app.imdata1.frame1  - app.initframe1;
                    %Target side
                    subRefTargEnd = app.imdata2.(['frame', num2str(app.framecount)]) - app.initframe2;
                    subRefTargStart = app.imdata2.frame1  - app.initframe2;
                    app.results.subrefRefEnd = subRefRefEnd;
                    app.results.subrefRefStart = subRefRefStart;
                    app.results.subrefTargEnd = subRefTargEnd;
                    app.results.subrefTargStart = subRefTargStart;
                end
            end
            %catch
            %   disp('Error when computing subtraction images for the cropped images. No image registration registration?'); 
            %end
            
 
            app.UIAxes.Visible = true;
            imshow(app.diffI, 'Parent', app.UIAxes);
            app.results.subtractImageWhole = app.diffI;
            app.results.subtractImageRef = substartendImRef;
            
        end

        % Button pushed function: GammaindexButton
        function GammaindexButtonPushed(app, event)
         %if isempty(app.gamma)
           messagebx = msgbox('Computing gammas (1/6 & 2/6)...');
           ref = struct;
           target = struct;
           refstruct = struct;
           targstruct = struct;
           if isempty(app.meanend) || isempty(app.meanstart)
               app.meanstart = (app.imdata.frame1 + app.imdata.frame2 + app.imdata.frame3 + app.imdata.frame4)/4;
                app.meanend = (app.imdata.(['frame', num2str(lastframe)]) + app.imdata.(['frame', num2str(lastframe-1)])...
                + app.imdata.(['frame', num2str(lastframe-2)]) + app.imdata.(['frame', num2str(lastframe-3)]))/4;
           end
           
           
           ref.start = [1,1];
           
           target.start = ref.start;
           ref.width = [1,1];
            
           targmax = median(app.meanend(:));
           refmax = median(app.meanstart(:));
           
           target.width = ref.width;
           ref.data = app.meanstart/refmax*25;
           target.data = app.meanend/targmax*25;
           
              
           %[app.gamma, app.pos, app.dif] =  CalcGamma(ref, target,2,15);
           disp('in Function');
           % Compute gamma for the cropped frames
           if app.BilateralCheckBox.Value == 0
               disp('bilat0');
                refstruct.start = [1,1];
                targstruct.start = refstruct;
                targstruct.width = ref.width;
                refstruct.width = ref.width;
                refstruct.data = app.imdata1.frame1;
                targstruct.data = app.imdata1.(['frame', num2str(app.framecount)]);
                [gammaStartEndRef, ~, ~] =  CalcGamma(refstruct, targstruct,2,15);
                app.results.gammaImageRef = gammaStartEndRef;
                if app.InitTempCheckBox.Value == 1
                    refstruct.data = app.initframe1;
                    targstruct.data = app.imdata1.frame1;
                    [gammaRefRefStart, ~, ~] = CalcGamma(refstruct, targstruct,2,15);
                    targstruct.data = app.imdata1.(['frame', num2str(app.framecount)]);
                    [gammaRefRefEnd, ~, ~] =  CalcGamma(refstruct, targstruct,2,15);
                    app.results.gammaRefRefEnd = gammaRefRefEnd;
                    app.results.gammaRefRefStart = gammaRefRefStart;
                end
           else
                disp('bilateral');
                refstruct.start = [1,1];
                targstruct.start = refstruct.start;
                targstruct.width = ref.width;
                refstruct.width = ref.width;
                refstruct.data = app.imdata1.frame1;
                targstruct.data = app.imdata1.(['frame', num2str(app.framecount)]);
                size(refstruct.data)
                size(targstruct.data)
                [gammaStartEndRef, ~, ~] =  CalcGamma(refstruct, targstruct,2,15);
                app.results.gammaImageRef = gammaStartEndRef;
                
                refstruct.data = app.imdata2.frame1;
                targstruct.data = app.imdata2.(['frame', num2str(app.framecount)]);
                [gammaStartEndTarg, ~, ~] =  CalcGamma(refstruct, targstruct,2,15);
                app.results.gammaImageTarg = gammaStartEndTarg;
                close(messagebx);
                
                if app.InitTempCheckBox.Value == 1
                    messagebx = msgbox('Computing 3/6 gamma against the reference frame...');
                    disp('refim');
                    % Reference image
                    refstruct.data = app.initframe1;
                    targstruct.data = app.imdata1.frame1;
                    [gammaRefRefStart, ~, ~] =  CalcGamma(refstruct, targstruct,2,15);
                    targstruct.data = app.imdata1.(['frame', num2str(app.framecount)]);
                    close(messagebx);
                    messagebx = msgbox('Computing 4/6 gamma against the reference frame...');
                    [gammaRefRefEnd, ~, ~] =  CalcGamma(refstruct, targstruct,2,15);
                    app.results.gammaRefRefEnd = gammaRefRefEnd;
                    app.results.gammaRefRefStart = gammaRefRefStart;
                    % Target image
                    refstruct.data = app.initframe2;
                    targstruct.data = app.imdata2.frame1;
                    close(messagebx);
                    messagebx = msgbox('Computing 5/6 gamma against the reference frame...');
                    [gammaRefTargStart, ~, ~] =  CalcGamma(refstruct, targstruct,2,15);
                    targstruct.data = app.imdata2.(['frame', num2str(app.framecount)]);
                    close(messagebx);
                    messagebx = msgbox('Computing 6/6 gamma against the reference frame...');
                    [gammaRefTargEnd, ~, ~] =  CalcGamma(refstruct, targstruct,2,15);
                    app.results.gammaRefTargEnd = gammaRefTargEnd;
                    app.results.gammaRefTargStart = gammaRefTargStart;
                    close(messagebx);
                end
            end
           
         %end
         %app.Image.Visible = true;
         app.UIAxes.Visible = true;
         %imshow(gammaRefTargEnd, 'Parent', app.UIAxes);
         %imshow(app.gamma, 'Parent', app.UIAxes);
         %app.results.gammaImage = app.gamma;
        end

        % Button pushed function: AddROIsButton
        function AddROIsButtonPushed(app, event)
            numberofROIs = app.NumberofROIsEditField.Value;

            % Set the number of angiosomes here
            if ~isfield(app.angiosomes, "names")
                app.angiosomes = struct;
                app.angiosomes.names(1) = "";
                disp("Angiosome structure created");
            end


            if app.DualdrawCheckBox.Value == 1 && app.BilateralCheckBox.Value == 1
                ROIpositionsref = struct;
                ROIpositionstarg = struct;
                for i = 1:numberofROIs
                    % If bilateral analysis and dual draw checked -> project 1st
                    str = inputdlg(['Name and draw the ROI number ', num2str(i)],'s');
                    app.angiosomes.names(end+1) = [str{1,1}, '_ref'];
                    app.angiosomes.names(end+1) = [str{1,1}, '_targ'];
                    % image ROIs to the 2nd image
                    [maskref, masktarg, hFH, hFH2] = drawDoubleImageROI(app.RGBvid.(['frame', num2str(1)]), app.RGBvid2.(['frame', num2str(1)]));
                    ROIpositionsref.(app.angiosomes.names(end-1)) = hFH;
                    ROIpositionstarg.(app.angiosomes.names(end)) = hFH2;
                    app.angiosomes.([str{1,1}, '_ref']) = maskref;
                    app.angiosomes.([str{1,1}, '_targ']) = masktarg;
                    %app.angiosomes;
                    % Save position
                    tempshape1 = polyshape(hFH(:,1), hFH(:,2));
                    tempshape2 = polyshape(hFH2(:,1), hFH2(:,2));
                    [app.angiosomes.(['centroid',str{1,1}, '_ref'])(2), app.angiosomes.(['centroid',str{1,1}, '_ref'])(1)] = centroid(tempshape1);
                    [app.angiosomes.(['centroid',str{1,1}, '_targ'])(2), app.angiosomes.(['centroid',str{1,1}, '_targ'])(1)] = centroid(tempshape2);
                    try
                        app.angiosomes.(['centroid',str{1,1}, '_ref'])(1) = floor(app.angiosomes.(['centroid',str{1,1}, '_ref'])(1));
                        app.angiosomes.(['centroid',str{1,1}, '_ref'])(2) = floor(app.angiosomes.(['centroid',str{1,1}, '_ref'])(2));
                        
                        app.angiosomes.(['centroid',str{1,1}, '_targ'])(1) = floor(app.angiosomes.(['centroid',str{1,1}, '_targ'])(1));
                        app.angiosomes.(['centroid',str{1,1}, '_targ'])(2) = floor(app.angiosomes.(['centroid',str{1,1}, '_targ'])(2));
                    catch
                        app.angiosomes.(['centroid',str{1,1}, '_ref'])(1) = ceil(app.angiosomes.(['centroid',str{1,1}, '_ref'])(1));
                        app.angiosomes.(['centroid',str{1,1}, '_ref'])(2) = floor(app.angiosomes.(['centroid',str{1,1}, '_ref'])(2)); 
                        
                        app.angiosomes.(['centroid',str{1,1}, '_targ'])(1) = ceil(app.angiosomes.(['centroid',str{1,1}, '_targ'])(1));
                        app.angiosomes.(['centroid',str{1,1}, '_targ'])(2) = floor(app.angiosomes.(['centroid',str{1,1}, '_targ'])(2)); 
                    end
                    %scatter(app.angiosomes.(['centroid',str{1,1},'_ref'])(1), app.angiosomes.(['centroid',str{1,1},'_ref'])(2));
                    %scatter(app.angiosomes.(['centroid',str{1,1},'_targ'])(1), app.angiosomes.(['centroid',str{1,1},'_targ'])(2));
                    
                end
                app.angiosomes.ROIpositions.ref = ROIpositionsref;
                app.angiosomes.ROIpositions.targ = ROIpositionstarg;
                if app.InitTempCheckBox.Value == 1
                    for i = 1:2
                        if i == 1
                            refnames = app.angiosomes.names(contains(app.angiosomes.names, 'ref'));
                            [app.angiosomes.ROIpositions.initref, app.angiosomes.initmasksref] = addInitImROIS(app.RGBinitref.frame1, ROIpositionsref, refnames);
                            % Get initial temperatures
                            for j = 1:length(refnames)
                                rname = convertStringsToChars(refnames(j));
                                app.angiosomes.initparams.([rname, 'temperatures']) = app.initframe1(app.angiosomes.initmasksref.(rname));   % Fetch temperature values from the original frame
                                app.angiosomes.([rname, 'initParams']) = {'mean', mean(app.angiosomes.initparams.([rname, 'temperatures']));...      
                                'std', std(app.angiosomes.initparams.([rname, 'temperatures'])); 'min', min(app.angiosomes.initparams.([rname, 'temperatures']));...
                                'max', max(app.angiosomes.initparams.([rname, 'temperatures']))};   % Compute mean, std, min and max 
        
                            end
                        else
                            targnames = app.angiosomes.names(contains(app.angiosomes.names, 'targ'));
                            [app.angiosomes.ROIpositions.inittarg, app.angiosomes.initmaskstarg] = addInitImROIS(app.RGBinittarg.frame1, ROIpositionstarg, targnames);
                            % Get initial temperatures
                            for j = 1:length(targnames)
                                tname = convertStringsToChars(targnames(j));
                                app.angiosomes.initparams.([tname, 'temperatures']) = app.initframe1(app.angiosomes.initmaskstarg.(tname));   % Fetch temperature values from the original frame
                                app.angiosomes.([tname, 'initParams']) = {'mean', mean(app.angiosomes.initparams.([tname, 'temperatures']));...      
                                'std', std(app.angiosomes.initparams.([tname, 'temperatures'])); 'min', min(app.angiosomes.initparams.([tname, 'temperatures']));...
                                'max', max(app.angiosomes.initparams.([tname, 'temperatures']))};   % Compute mean, std, min and max 
                            end
                        end
                    end
                end
                
            else
                figax = figure(1);
                imshow(app.RGBvid.(['frame', num2str(1)]));
                ROIpositionsref = struct;
                % Set to full screen
                set(gcf, 'Position', get(0,'Screensize'));
                hold on;
                % Draw all ROI's one by one
                for i = 1:numberofROIs + numberofROIs*app.BothimagesCheckBox.Value
                    
                    if i <= numberofROIs
                        disp('Reference image ROIs');
                        str = inputdlg(['Draw and name the ROI number ', num2str(i),', for the 1st image:'],'s');
                        app.angiosomes.names(end+1) = str;
                        % Draw
                        hFH = drawfreehand();
                        ROIpositionsref.(app.angiosomes.names(end)) = hFH;
                        % Create binary mask and add it to angiosome-structure with input name
                        tempmask = hFH.createMask();
                        app.angiosomes.(str{1,1}) = tempmask;
                        % Save position
                        tempshape = polyshape(hFH.Position(:,1), hFH.Position(:,2));
                        [app.angiosomes.(['centroid',str{1,1}])(2), app.angiosomes.(['centroid',str{1,1}])(1)] = centroid(tempshape);
                        try
                            app.angiosomes.(['centroid',str{1,1}])(1) = floor(app.angiosomes.(['centroid',str{1,1}])(1));
                            app.angiosomes.(['centroid',str{1,1}])(2) = floor(app.angiosomes.(['centroid',str{1,1}])(2)); 
                        catch
                            app.angiosomes.(['centroid',str{1,1}])(1) = ceil(app.angiosomes.(['centroid',str{1,1}])(1));
                            app.angiosomes.(['centroid',str{1,1}])(2) = floor(app.angiosomes.(['centroid',str{1,1}])(2)); 
                        end
                        
                        
                    else
                        disp('Target image ROIs');
                        str = inputdlg(['Draw and name the ROI number ', num2str(i - numberofROIs),', for the 2nd image:'],'s');
                        
                        app.angiosomes.names(end+1) = str;
                        
                        % Draw
                        hFH = drawfreehand();
                        ROIpositionsref.(app.angiosomes.names(end)) = hFH;
                        % Create binary mask and add it to angiosome-structure with input name
                        tempmask = hFH.createMask();
                        app.angiosomes.(str{1,1}) = tempmask;
                        % Save position
                        % Save position
                        tempshape = polyshape(hFH.Position(:,1), hFH.Position(:,2));
                        
                        [app.angiosomes.(['centroid',str{1,1}])(2), app.angiosomes.(['centroid',str{1,1}])(1)] = centroid(tempshape);
          
                        try
                            app.angiosomes.(['centroid',str{1,1}])(1) = floor(app.angiosomes.(['centroid',str{1,1}])(1));
                            app.angiosomes.(['centroid',str{1,1}])(2) = floor(app.angiosomes.(['centroid',str{1,1}])(2)); 
                        catch
                            app.angiosomes.(['centroid',str{1,1}])(1) = ceil(app.angiosomes.(['centroid',str{1,1}])(1));
                            app.angiosomes.(['centroid',str{1,1}])(2) = floor(app.angiosomes.(['centroid',str{1,1}])(2)); 
                        end
                        scatter(app.angiosomes.(['centroid',str{1,1}])(1), app.angiosomes.(['centroid',str{1,1}])(2));
                    end
                end
                app.angiosomes.ROIpositions.ref = ROIpositionsref;
                if app.InitTempCheckBox.Value == 1
                    if i == 1
                        [app.angiosomes.ROIpositions.initref, app.angiosomes.initmasksref] = addInitImROIS(app.RGBinitref.frame1, ROIpositionsref, app.angiosomes.names);
                        for j = 1:length(length(app.angiosomes.names))
                            app.angiosomes.initparams.([app.angiosomes.names(j), 'temperatures']) = app.initframe1(app.angiosomes.initmasksref.(app.angiosomes.names(j)));   % Fetch temperature values from the original frame
                            app.angiosomes.([app.angiosomes.names(j), 'initParams']) = {'mean', mean(app.angiosomes.initparams.([app.angiosomes.names(j), 'temperatures']));...      
                            'std', std(app.angiosomes.initparams.([app.angiosomes.names(j), 'temperatures'])); 'min', min(app.angiosomes.initparams.([app.angiosomes.names(j), 'temperatures']));...
                            'max', max(app.angiosomes.initparams.([app.angiosomes.names(j), 'temperatures']))};   % Compute mean, std, min and max 
                        end
                    end
                end
                hold off;
                close(figure(1));
            end
            %pause(5);
            
          %  listboxnames = {};
          % for i = 1:length(app.angiosomes.names)
          %     listboxnames{end+1} = app.angiosomes.names{i};
          % end
          
          app.ROIsListBox.Items = app.angiosomes.names;

            
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            app.ROIvalues = app.ROIsListBox.Value;
            
            if app.BilateralCheckBox.Value == 1
                app.fuseim = imfuse(app.imdata1.(['frame', num2str(5)]), app.imdata2.(['frame', num2str(5)]));
            else
                app.fuseim = app.imdata1.(['frame', num2str(5)]);
            end
            for i = 1:length(app.ROIvalues)
                tempROI = app.angiosomes.(app.ROIvalues{i});
                app.fuseim = imfuse(app.fuseim, tempROI);
                % Add centoroids
                app.fuseim(app.angiosomes.(['centroid',(app.ROIvalues{i})])(1), app.angiosomes.(['centroid',(app.ROIvalues{i})])(2)) = 1;
            end
            imshow(app.fuseim, 'Parent', app.UIAxes);
        end

        % Button pushed function: MeanstdminmaxButton
        function MeanstdminmaxButtonPushed(app, event)
            %% Analyze angiosomes

            % Compute % Relative sizes. Save to structure
            Afields = app.ROIvalues;
            disp(Afields);
            largestsum = 0;
            for i = 1:length(Afields)
                try
                    newsum = sum(sum(app.angiosomes.(Afields{i})));
                catch
                   disp('names...');
                   newsum = -1;
                end
                if newsum >= largestsum
                    largestsum = newsum;
                    idx = i;
                    
                end
            end
            
            for i = 1:length(Afields)
                
                if contains(Afields{i}, 'targ')
                    fvid = app.floatvid2;
                else
                    fvid = app.floatvid;
                end

                for j = 1:length(fieldnames(fvid))
                    if j == 1 && ~contains(Afields{i}, 'names') 
                        
                        app.angiosomes.([Afields{i}, 'size']) = bwarea(app.angiosomes.(Afields{i}))/bwarea(app.angiosomes.(Afields{idx}));    % Compute area from the masked image
                        app.angiosomes.([Afields{i}, 'temperatures']) = fvid.(['frame', num2str(j)])(app.angiosomes.(Afields{i}));   % Fetch temperature values from the original frame
                        app.angiosomes.([Afields{i}, 'Params']) = {'mean', mean(app.angiosomes.([Afields{i}, 'temperatures']));...      
                        'std', std(app.angiosomes.([Afields{i}, 'temperatures'])); 'min', min(app.angiosomes.([Afields{i}, 'temperatures']));...
                        'max', max(app.angiosomes.([Afields{i}, 'temperatures']))};


                    elseif ~contains(Afields{i}, 'names')
                        app.angiosomes.([Afields{i}, 'size']) = bwarea(app.angiosomes.(Afields{i}))/bwarea(app.angiosomes.(Afields{idx}));    % Compute area from the masked image
                        app.angiosomes.([Afields{i}, 'temperatures'])(:,end+1) = fvid.(['frame', num2str(j)])(app.angiosomes.(Afields{i}));   % Fetch temperature values from the original frame
                        app.angiosomes.([Afields{i}, 'Params'])(1:4,end+1) = {mean(app.angiosomes.([Afields{i}, 'temperatures'])(:,end));...      
                             std(app.angiosomes.([Afields{i}, 'temperatures'])(:,end)); min(app.angiosomes.([Afields{i}, 'temperatures'])(:,end));...
                            max(app.angiosomes.([Afields{i}, 'temperatures'])(:,end))};   % Compute mean, std, min and max
                    end
                    
                    
                end
                if app.InitTempCheckBox.Value == 1
                    length(Afields)
                    length(fieldnames(fvid))
                    app.angiosomes.([Afields{i}, 'Params'])(end+1,1:2) = {'Tstart-T0', app.angiosomes.([Afields{i}, 'Params']){1,2} - ...
                        app.angiosomes.([Afields{i}, 'initParams']){1,2}};
                    app.angiosomes.([Afields{i}, 'Params'])(end+1,1:2) = {'Tend-T0', app.angiosomes.([Afields{i}, 'Params']){1,end} - ...
                        app.angiosomes.([Afields{i}, 'initParams']){1,2}};
                end
                % delete this. Just for testing
                tempsave = app.angiosomes;
                save('angiosomes', 'tempsave');
                
                
                if app.lpassCheckBox.Value == 1
                   % app.angiosomes.([Afields{i}, 'Params'])(2:end)
                    inputdata = cell2mat(app.angiosomes.([Afields{i}, 'Params'])(1,2:end));
                    length(app.angiosomes.([Afields{i}, 'Params'])(1,2:end))
                    outputdata = num2cell(lowpassfilterIRdata(inputdata, 0.15, app.fs));
                    app.angiosomes.([Afields{i}, 'Params'])(1,2:length(outputdata)+1) = outputdata;
                end
                app.results.timeconstants.(Afields{i}).params = app.angiosomes.([Afields{i}, 'Params']);
                
            end
            %% plot the temperature data for each angiosome and the foot. Present them in subplots for results

            figure(1);
            axesf = axes('Parent',figure(1));
            timev = 1:length(app.angiosomes.([Afields{idx}, 'Params'])(1,:))-2;
            timev = timev./app.fs./60;
           % app.UIAxes.cla;
           
            hold(axesf, 'On');
        
            %app.UIAxes.XLimMode = 'manual';
            axesf.XLim = [timev(1) (timev(end) + timev(end)*0.2)];
            %app.UIAxes.Visible = true;
          
            %axis(axesf, "normal");
            grid(axesf, "on");
            %app.UIAxes.Layer = "top";
            xlabel(axesf, 'time [min]');
            %xticks(app.UIAxes, "auto")
            ylabel(axesf, 'Temperature [C]');
            axesf.YLimMode = 'manual';
            
            datamin = 10000000;
            datamax = -10000000;
            lenan = length(Afields);
            for i = 1:lenan
                datatemp = cell2mat(app.angiosomes.([Afields{i}, 'Params'])(1,2:end-1));
                %plot(app.UIAxes, timev, smooth(datatemp));
                plot(axesf, timev, smooth(datatemp));
                
                tempmax = max(datatemp);
                tempmin = min(datatemp);
                if tempmax > datamax
                    datamax = tempmax;
                end
                if tempmin < datamin
                    datamin = tempmin;
                end
                
            end
            
            axesf.YLim = [datamin - 0.5, datamax + 0.5];
            %axesf.YDir = 'reverse';
            %legend(app.UIAxes, Afields);
            legend(axesf, regexprep(Afields,'_',' '));
            drawnow
            hold(axesf, "off");
            
            app.ArtefactsButton.Visible = 1;
            %hold(app.UIAxes, "off");
            
        end

        % Button pushed function: ThermalanalysisButton
        function ThermalanalysisButtonPushed(app, event)
            % Curve fitting
            figure(1);
            axesf = axes('Parent',figure(1));
      
           % app.UIAxes.cla;
           
            hold(axesf, 'On');
        
            %app.UIAxes.XLimMode = 'manual';
            
            %app.UIAxes.Visible = true;
          
            %axis(axesf, "normal");
            grid(axesf, "on");
            %app.UIAxes.Layer = "top";
            xlabel(axesf, 'time [min]');
            %xticks(app.UIAxes, "auto")
            ylabel(axesf, 'Temperature [C]');
            axesf.YLimMode = 'auto';
    
            
            Afields = app.ROIvalues;
            
            for i = 1:length(Afields)
                %try
                timev = 1:length(app.angiosomes.([Afields{i}, 'Params'])(1,:))-2;
                timev = timev./app.fs./60;
                T = cell2mat(app.angiosomes.([Afields{i}, 'Params'])(1,2:round(end-1)/2))';
                timev = timev(1:length(T));
                %
                % FIt equation 𝑇(𝑡) = 𝑇0 * 𝑒−𝑡/𝜏 + 𝐴𝜏(1 − 𝑒−𝑡/𝜏) to the data
                g = fittype('T0*exp(-timev/tau)+A*tau*(1-exp(-timev/tau))','dependent',{'T'},'independent',{'timev'},...
                    'coeff',{'A','tau', 'T0'});
            
                % Goodness of fit
                [myfit, got] = fit(timev', T, g);

            
                % Plot fit
                scatter(axesf, timev, T);
                plot(myfit);
                % Results
                fieldnames(myfit)
                app.results.timeconstants.(Afields{i}).tau = myfit.tau;
                app.results.timeconstants.(Afields{i}).A = myfit.A;
                app.results.timeconstants.(Afields{i}).T0 = myfit.T0;
                app.results.timeconstants.(Afields{i}).GOT = got;
                
                % display results
                disp("Goodness of fit parameters: ");
                disp(got);
                disp(['Timeconstant for ', Afields{i}, ': ', num2str(myfit.tau), 'min']);
               % catch
               %    disp('One or more of the requested ROI result field not existing.'); 
                %end
                
            end
            axesf.XLim = [timev(1) (timev(end) + timev(end)*0.2)];
            % Create result tables from the thermo analysis
            
           [tempres, gotres] = temptable(app.results, Afields);
            app.results.timeconstants.recovtable = tempres; 
            app.results.timeconstants.gotparams = gotres; 
            
            
            hold off;
            %


        end

        % Button pushed function: SavematButton
        function SavematButtonPushed(app, event)
            newfilename = app.NameEditField.Value;
            savedata = app.results;
            save([newfilename, '.mat'], 'savedata');
        end

        % Button pushed function: SpectralflowButton
        function SpectralflowButtonPushed(app, event)
            if app.savepath == ""
                selpath = uigetdir;
                app.savepath = selpath;
            end
            
            prompt = {'Bandpass lower limit', 'bandpass higher limit'};
            dlgtitle = 'Set range for bandpass filtering. 0.005-0.02 Hz, 0.02-0.05 Hz, 0.05-0.15 Hz, 0.15-0.4 Hz, and 0.4-2.0 Hz), corresponding to endothelial (metabolic), neurogenic, myogenic, respiratory, and cardiac origins';
            definput = {'0.0001', '0.02'};
            dims = [1 150];
            newline = inputdlg(prompt,dlgtitle,dims,definput);
            
            bandpassrange = [str2double(newline{1}), str2double(newline{2})];
            meanvals = {};
            for i = 1:length(app.ROIvalues)
                inputROIs{i} =  app.angiosomes.(app.ROIvalues{i});
                meanvals{i} = cell2mat(app.results.timeconstants.(app.ROIvalues{i}).params(1,2:end));
            end
            length(meanvals)
            % Determine if spectralflow function should swap the input
            % data and/or perform the analysis to 2 separate images (bilateral)
            targcount = sum(find(contains(app.ROIvalues, '_targ')));
            refcount = sum(find(contains(app.ROIvalues, '_ref')));


            if targcount > 0 && refcount > 0
                [BF, bandpassBF, flowrange, timevect] = spectralflow(app.imdata1, bandpassrange, inputROIs, app.ROIvalues, app.fs, meanvals, app.imdata2,1);
            elseif targcount > 0 && refcount == 0
                [BF, bandpassBF, flowrange, timevect] = spectralflow(app.imdata2, bandpassrange, inputROIs, app.ROIvalues, app.fs, meanvals, app.imdata1,1);
            else
                [BF, bandpassBF, flowrange, timevect] = spectralflow(app.imdata1, bandpassrange, inputROIs, app.ROIvalues, app.fs,meanvals, app.imdata1, 1);
            end
            

%           %% Play video for the first ROI
%           %numofflowsteps = 25;
%           % Find maximum flow
%           BF.(app.ROIvalues{1}).frame1(BF.(app.ROIvalues{1}).frame1 == inf) = 0;
%           flowrangemax = max(max(max(BF.(app.ROIvalues{1}).frame1(:,:,:))));
%           % Find minimum flow
%           BF.(app.ROIvalues{1}).frame1(BF.(app.ROIvalues{1}).frame1 <= 0) = inf;
%           flowrangemin = min(min(min(min(BF.(app.ROIvalues{1}).frame1(:,:,:))),[],2));
%           BF.(app.ROIvalues{1}).frame1(BF.(app.ROIvalues{1}).frame1 == inf) = 0;
%           % Define flow range
%           %flowrange = floor(flowrangemin):(flowrangemax - flowrangemin)/numofflowsteps:ceil(flowrangemax);
%           flowrange = floor(flowrangemin):1:ceil(flowrangemax);
%           % Play IR-video
            messagebx = msgbox('Computing flow plots...');
            videospeed = 0.0001;
            scalediv = 1;
            flowrange(flowrange == 0) = 0;
            flowrange(flowrange <= 0) = inf;
            flowrange(flowrange == inf) = 0;
            %linearindices =  find(BF.(app.ROIvalues{1}).frame1);
            % Plot and save bandpassflow from each angiosome
            lenroi = length(app.ROIvalues);
            fh = figure(1);
            fh.WindowState = 'maximized';
            hold on
            for j = 1:lenroi
                %length(fieldnames(BF.(app.ROIvalues{j})))
                for i = 1:length(fieldnames(BF.(app.ROIvalues{j})))
                    sum1 = BF.([app.ROIvalues{j},'_mean']);%BF.(app.ROIvalues{j}).(['frame', num2str(i)])(app.angiosomes.(['centroid',app.ROIvalues{j}])(1), app.angiosomes.(['centroid',app.ROIvalues{j}])(2));
                    %sum1 = sum1 + BF.(app.ROIvalues{j}).(['frame', num2str(i)])(app.angiosomes.(['centroid',app.ROIvalues{j}])(1)+1, app.angiosomes.(['centroid',app.ROIvalues{j}])(2));
                    %sum1 = sum1 + BF.(app.ROIvalues{j}).(['frame', num2str(i)])(app.angiosomes.(['centroid',app.ROIvalues{j}])(1)-1, app.angiosomes.(['centroid',app.ROIvalues{j}])(2));
                    %sum1 = sum1 + BF.(app.ROIvalues{j}).(['frame', num2str(i)])(app.angiosomes.(['centroid',app.ROIvalues{j}])(1)+1, app.angiosomes.(['centroid',app.ROIvalues{j}])(2)+1);
                    %sum1 = sum1 + BF.(app.ROIvalues{j}).(['frame', num2str(i)])(app.angiosomes.(['centroid',app.ROIvalues{j}])(1)+1, app.angiosomes.(['centroid',app.ROIvalues{j}])(2)-1);
                    %sum1 = sum1 + BF.(app.ROIvalues{j}).(['frame', num2str(i)])(app.angiosomes.(['centroid',app.ROIvalues{j}])(1)-1, app.angiosomes.(['centroid',app.ROIvalues{j}])(2)-1);
                    %sum1 = sum1 + BF.(app.ROIvalues{j}).(['frame', num2str(i)])(app.angiosomes.(['centroid',app.ROIvalues{j}])(1)-1, app.angiosomes.(['centroid',app.ROIvalues{j}])(2)+1);
                    %sum1 = sum1 + BF.(app.ROIvalues{j}).(['frame', num2str(i)])(app.angiosomes.(['centroid',app.ROIvalues{j}])(1), app.angiosomes.(['centroid',app.ROIvalues{j}])(2)-1);
                    %sum1 = sum1 + BF.(app.ROIvalues{j}).(['frame', num2str(i)])(app.angiosomes.(['centroid',app.ROIvalues{j}])(1), app.angiosomes.(['centroid',app.ROIvalues{j}])(2)+1);
                    plotthis{j} = sum1;%/9
                   
                end
                plot(timevect/60, plotthis{j});
                app.results.derivedBF.(app.ROIvalues{j}) = plotthis{j};
            end 
            
            legend(regexprep(app.ROIvalues,'_',' '));
            title('Spectral flow', 'fontsize', 20);
            ylabel('Blood flow [arb. units]', 'fontsize', 18);
            xlabel('time [min]', 'fontsize', 18);
            close(messagebx);
            
            hold off
            
            drawnow;
            saveas(fh, [app.savepath, '\spectralFlow']);
            %pause(5);
            %[~, ~] = playIrVideo(BF.(app.ROIvalues{1}), bandpassBF.(app.ROIvalues{1}), flowrange, videospeed, scalediv,1,0,1, app.x, app.y, 1, length(fieldnames(BF.(app.ROIvalues{1}))));
            
            
        end

        % Button pushed function: SpectralbandsButton
        function SpectralbandsButtonPushed(app, event)
            %if app.savepath
                selpath = uigetdir;
                app.savepath = selpath;
            %end
            
            % Need to compute parameters first
            fieldnames(app.results.timeconstants)
            if isfield(app.results.timeconstants, app.ROIvalues{1})
                for i = 1:length(app.ROIvalues)
                    inputROIs{i} =  app.results.timeconstants.(app.ROIvalues{i}).params;
                end

                highestfreq = 2;
                if app.fs < 4
                    highestfreq = floor(app.fs/2);
                    if highestfreq == 0
                        highestfreq = 1;
                    end
                    disp(['Nyquist range exceeded -> Cardiac band limited to ', num2str(highestfreq), 'Hz']);
                end
                disp(app.fs)
                [filteredData, powerspectfft] = bandpassfilterIRdata(inputROIs, app.ROIvalues, app.fs, [0.005,0.02], [0.02,0.05], [0.05, 0.15], [0.15,0.4], [0.4, highestfreq]);
                
                
                %% Plot the data for the ROIs
                set(0,'defaultTextFontName','Courier');
                
                for i = 1:length(app.ROIvalues)
                    
                    disp(['round', num2str(i)]);
                    disp(num2str(i + mod(1,i)));
                    
                    fh = figure(i);
                    fh.WindowState = 'maximized';
                    titlesize = 15;
                    ticksize = 10;
                    xlabs = 12;
                    ylabs = 12;
                    
                    hold on;
                    title([app.ROIvalues{i}, ' Filtered']);
                    subplot(5,2,1);
                    length(filteredData.timev)
                    length(filteredData.(app.ROIvalues{i}).metabolic)
                    plot(filteredData.timev,filteredData.(app.ROIvalues{i}).dmldata);
                    a = get(gca,'XTickLabel');
                    set(gca,'XTickLabel',a,'FontName','Times','fontsize',ticksize)
                    title([regexprep(app.ROIvalues{i}, '_', ' '), ' Original'], 'fontsize', titlesize);
                    xlabel("time [s]", 'fontsize', xlabs);
                    ylabel("Arb. units", 'fontsize', ylabs);
                    subplot(5,2,2);
                    plot(filteredData.timev,filteredData.(app.ROIvalues{i}).metabolic);
                    a = get(gca,'XTickLabel');
                    set(gca,'XTickLabel',a,'FontName','Times','fontsize',ticksize)
                    title('Endothelic','fontsize', titlesize);
                    xlabel("time [s]", 'fontsize', xlabs);
                    ylabel("Arb. units", 'fontsize', ylabs);
                    subplot(5,2,3);
                    plot(filteredData.timev,filteredData.(app.ROIvalues{i}).neurogenic);
                    a = get(gca,'XTickLabel');
                    set(gca,'XTickLabel',a,'FontName','Times','fontsize',ticksize)
                    title('Neurogenic','fontsize', titlesize);
                    xlabel("time [s]", 'fontsize', xlabs);
                    ylabel("Arb. units", 'fontsize', ylabs);
                    subplot(5,2,4);
                    plot(filteredData.timev,filteredData.(app.ROIvalues{i}).myogenic);
                    a = get(gca,'XTickLabel');
                    set(gca,'XTickLabel',a,'FontName','Times','fontsize',ticksize)
                    title('Myogenic','fontsize', titlesize);
                    xlabel("time [s]", 'fontsize', xlabs);
                    ylabel("Arb. units", 'fontsize', ylabs);
                    subplot(5,2,5);
                    plot(filteredData.timev,filteredData.(app.ROIvalues{i}).respiratory);
                    a = get(gca,'XTickLabel');
                    set(gca,'XTickLabel',a,'FontName','Times','fontsize',ticksize)
                    title('Respiratory','fontsize', titlesize);
                    xlabel("time [s]", 'fontsize', xlabs);
                    ylabel("Arb. units", 'fontsize', ylabs);
                    subplot(5,2,6);
                    plot(filteredData.timev,filteredData.(app.ROIvalues{i}).cardiac);
                    a = get(gca,'XTickLabel');
                    set(gca,'XTickLabel',a,'FontName','Times','fontsize',ticksize)
                    title(gca,'Cardiac','fontsize', titlesize);
                    xlabel("time [s]", 'fontsize', xlabs);
                    ylabel("Arb. units", 'fontsize', ylabs);
                    
                    subplot(5,2,[7,10]);
                    plot(powerspectfft.(app.ROIvalues{i}).f,abs(powerspectfft.(app.ROIvalues{i}).fdmldata));
                    
                    set(gca, 'YScale', 'log');
                    set(gca, 'XScale', 'log');
                    a = get(gca,'XTickLabel');
                    set(gca,'XTickLabel',a,'FontName','Times','fontsize',ticksize)
                    title([regexprep(app.ROIvalues{i}, '_', ' '), ' Power spectrum'], 'fontsize', titlesize);
                    xlabel("Frequency [Hz]", 'fontsize', 20);
                    ylabel("Power/Frequency (dB/Hz)", 'fontsize', 20);
                    drawnow
                    hold off;
     
                    saveas(fh, [app.savepath, '\spectralBands', app.ROIvalues{i}]);
                    
                    app.results.spectralBands.timeVect = filteredData.timev;
                    app.results.spectralBands.(app.ROIvalues{i}).original = filteredData.(app.ROIvalues{i}).dmldata;
                    app.results.spectralBands.(app.ROIvalues{i}).metabolic = filteredData.(app.ROIvalues{i}).metabolic;
                    app.results.spectralBands.(app.ROIvalues{i}).neurogenic = filteredData.(app.ROIvalues{i}).neurogenic;
                    app.results.spectralBands.(app.ROIvalues{i}).myogenic = filteredData.(app.ROIvalues{i}).myogenic;
                    app.results.spectralBands.(app.ROIvalues{i}).respiratory = filteredData.(app.ROIvalues{i}).respiratory;
                    app.results.spectralBands.(app.ROIvalues{i}).cardiac = filteredData.(app.ROIvalues{i}).cardiac;
                    app.results.spectralBands.(app.ROIvalues{i}).freqax = powerspectfft.(app.ROIvalues{i}).f;
                    app.results.spectralBands.(app.ROIvalues{i}).powerspectrum = abs(powerspectfft.(app.ROIvalues{i}).fdmldata);
                end


    %             Endothelic (metabolic) 0.005-0.02 Hz
    %             metabolic = bandpass(dmldata, [0.005,0.02], app.fs);
    %             Neurogenic 0.02-0.05 Hz
    %             neurogenic = bandpass(dmldata, [0.02,0.05], app.fs);
    %             Myogenic 0.15 - 0.4 Hz
    %             myogenic = bandpass(dmldata, [0.05, 0.15], app.fs);
    %             Resporatory 
    %             respiratory = bandpass(dmldata, [0.15,0.4], app.fs);
    %             Cardiac 0.4 - 2 Hz
    %             cardiac = bandpass(dmldata, [0.4, 2], app.fs);
            else
                uialert(uifigure,'Mean temperatures not computed', 'Compute max, min, mean and std values first');
            end
            
        end

        % Button pushed function: WaveletsButton
        function WaveletsButtonPushed(app, event)
            % Save to resuts
            fighandle = figure(1);
            lenroi = length(app.ROIvalues);
            for i = 1:lenroi
                datatemp = cell2mat(app.results.timeconstants.(app.ROIvalues{i}).params(1,2:end-1));
                [app.results.(app.ROIvalues{i}).wavelets.wt, app.results.(app.ROIvalues{i}).wavelets.f, ...
                    app.results.(app.ROIvalues{i}).wavelets.coi] = computeWavelets(datatemp, app.fs, i, fighandle, lenroi, app.ROIvalues{i});
            end
            disp('Wavelet results saved to results');
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            app.colormaprangevect = floor(app.mintemp):app.ColormapresolutionKEditField.Value:ceil(app.maxtemp);
            % Use default colormap to compute shorter colormap
            app.shortcmap = computeNewColorMap(app.colormaprangevect, value);
            % Update figure
            event2 = app.lastsliderevent;
            if strcmp(app.lastslider, 'Start')
                StarttimeSliderValueChanging(app, event2)
            else
                EndtimeSliderValueChanging(app, event2)
            end
        end

        % Value changed function: ColormapresolutionKEditField
        function ColormapresolutionKEditFieldValueChanged(app, event)
            value = app.ColormapresolutionKEditField.Value;
            app.colormaprangevect = floor(app.mintemp):value:ceil(app.maxtemp);
            % Use default colormap to compute shorter colormap
            app.shortcmap = computeNewColorMap(app.colormaprangevect, app.ColormapDropDown.Value);
            % Update figure
            event2 = app.lastsliderevent;
            if strcmp(app.lastslider, 'Start')
                StarttimeSliderValueChanging(app, event2)
            else
                EndtimeSliderValueChanging(app, event2)
            end
        end

        % Value changed function: InitTempCheckBox
        function InitTempCheckBoxValueChanged(app, event)
            value = app.InitTempCheckBox.Value;
            if value == 1
                prompt = {'Initial frame: '};
                dlgtitle = 'Initial frame number before modulation';
                definput = {'1'};
                dims = [1 50];
                app.initframe = inputdlg(prompt,dlgtitle,dims,definput);
            end
        end

        % Button pushed function: ArtefactsButton
        function ArtefactsButtonPushed(app, event)
            Afields = app.ROIvalues;
            timevect = (0:(length(app.angiosomes.([Afields{1}, 'Params'])(1,2:end))-1))/app.fs;
            
            prompt = {'Highpass freq. [Hz]: '};
            dlgtitle = 'Set highpass limit';
            definput = {'0.02'};
            dims = [1 50];
            arteflims = [];
            repairlims = [];
            repairlimsinit = [];
            method = [];
            hpasslim = inputdlg(prompt,dlgtitle,dims,definput);
            for i = 1:length(Afields)
                if app.ApplytoallCheckBox.Value == 1
                    if i == 1
                        [tempoutput, arteflims, repairlims, repairlimsinit, offsetp, offsetv, method] = removeArtefact(timevect, cell2mat(app.results.timeconstants.(Afields{i}).params(1,2:end))', app.fs, str2double(hpasslim), 0, [], [], [], [], [], []);
                    else
                        [tempoutput, ~, ~, ~, ~, ~, ~] = removeArtefact(timevect, cell2mat(app.results.timeconstants.(Afields{i}).params(1,2:end))', app.fs, str2double(hpasslim), app.ApplytoallCheckBox.Value, arteflims, repairlims, repairlimsinit, offsetp, offsetv, method);
                    end
                else
                    [tempoutput, ~, ~, ~, ~, ~, ~] = removeArtefact(timevect, cell2mat(app.results.timeconstants.(Afields{i}).params(1,2:end))', app.fs, str2double(hpasslim), 0, [],[], [], [], [], []);
                end
                app.angiosomes.([Afields{i}, 'Params'])(1,2:length(tempoutput)+1) = num2cell(tempoutput);
                app.results.timeconstants.(Afields{i}).params(1,2:length(tempoutput)+1) = num2cell(tempoutput); 
                %plot(cell2mat(app.angiosomes.([Afields{i}, 'Params'])(2:length(tempoutput)+1)))
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ImportdataButton = uibutton(app.UIFigure, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Tag = 'importPushButton';
            app.ImportdataButton.Tooltip = {'Import .seq data or parsed exel data'};
            app.ImportdataButton.Text = 'Import data';
            app.PreprocessingtoolsPanel = uipanel(app.UIFigure);
            app.PreprocessingtoolsPanel.Title = 'Preprocessing tools';
            app.ReduceframesButton = uibutton(app.PreprocessingtoolsPanel, 'push');
            app.ReduceframesButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceframesButtonPushed, true);
            app.ReduceframesButton.Tag = 'reduceFramesPushButton';
            app.ReduceframesButton.Text = 'Reduce frames';
            app.CropButton = uibutton(app.PreprocessingtoolsPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.NormalizetoareaButton = uibutton(app.PreprocessingtoolsPanel, 'push');
            app.NormalizetoareaButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizetoareaButtonPushed, true);
            app.NormalizetoareaButton.Text = 'Normalize to area';
            app.AutoregisterButton = uibutton(app.PreprocessingtoolsPanel, 'push');
            app.AutoregisterButton.ButtonPushedFcn = createCallbackFcn(app, @AutoregisterButtonPushed, true);
            app.AutoregisterButton.Text = 'Auto register';
            app.BilateralCheckBox = uicheckbox(app.PreprocessingtoolsPanel);
            app.BilateralCheckBox.Text = 'Bilateral';
            app.BilateralCheckBox.Value = true;
            app.InitTempCheckBox = uicheckbox(app.PreprocessingtoolsPanel);
            app.InitTempCheckBox.ValueChangedFcn = createCallbackFcn(app, @InitTempCheckBoxValueChanged, true);
            app.InitTempCheckBox.Text = 'Init. Temp';
            app.StarttimeSliderLabel = uilabel(app.UIFigure);
            app.StarttimeSliderLabel.Text = 'Start time';
            app.StarttimeSlider = uislider(app.UIFigure);
            app.StarttimeSlider.Limits = [1 100];
            app.StarttimeSlider.ValueChangingFcn = createCallbackFcn(app, @StarttimeSliderValueChanging, true);
            app.StarttimeSlider.Value = 1;
            app.EndtimeSliderLabel = uilabel(app.UIFigure);
            app.EndtimeSliderLabel.Text = 'End time';
            app.EndtimeSlider = uislider(app.UIFigure);
            app.EndtimeSlider.Limits = [1 100];
            app.EndtimeSlider.ValueChangingFcn = createCallbackFcn(app, @EndtimeSliderValueChanging, true);
            app.EndtimeSlider.Value = 100;
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.Fr9HzLabel = uilabel(app.ParametersPanel);
            app.Fr9HzLabel.Text = 'Fr = 9 Hz';
            app.StartsframeLabel = uilabel(app.ParametersPanel);
            app.StartsframeLabel.Text = 'Start [s/frame]:';
            app.EndsframeLabel = uilabel(app.ParametersPanel);
            app.EndsframeLabel.Text = 'End [s/frame]: ';
            app.FramestotLabel = uilabel(app.ParametersPanel);
            app.FramestotLabel.Text = 'Frames tot: ';
            app.ComputePanel = uipanel(app.UIFigure);
            app.ComputePanel.Title = 'Compute';
            app.ComputeRGBButton = uibutton(app.ComputePanel, 'push');
            app.ComputeRGBButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeRGBButtonPushed, true);
            app.ComputeRGBButton.Text = 'Compute RGB';
            app.ShowvidCheckBox = uicheckbox(app.ComputePanel);
            app.ShowvidCheckBox.Text = 'Show vid';
            app.ShowvidCheckBox.Value = true;
            app.ColormapDropDownLabel = uilabel(app.ComputePanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.ComputePanel);
            app.ColormapDropDown.Items = {'Jet', 'gaussianGreen', 'cool', 'hot', 'hsv', 'gray', 'copper', 'colorcube', 'spring', 'Parula', 'Turbo'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Jet';
            app.ColormapresolutionKEditFieldLabel = uilabel(app.ComputePanel);
            app.ColormapresolutionKEditFieldLabel.Text = 'Colormap resolution [K]';
            app.ColormapresolutionKEditField = uieditfield(app.ComputePanel, 'numeric');
            app.ColormapresolutionKEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapresolutionKEditFieldValueChanged, true);
            app.ColormapresolutionKEditField.Value = 1;
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.SubtractButton = uibutton(app.AnalysisPanel, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Text = 'Subtract';
            app.GammaindexButton = uibutton(app.AnalysisPanel, 'push');
            app.GammaindexButton.ButtonPushedFcn = createCallbackFcn(app, @GammaindexButtonPushed, true);
            app.GammaindexButton.Text = 'Gamma index';
            app.MeanstdminmaxButton = uibutton(app.AnalysisPanel, 'push');
            app.MeanstdminmaxButton.ButtonPushedFcn = createCallbackFcn(app, @MeanstdminmaxButtonPushed, true);
            app.MeanstdminmaxButton.Text = 'Mean, std, min, max';
            app.SpectralflowButton = uibutton(app.AnalysisPanel, 'push');
            app.SpectralflowButton.ButtonPushedFcn = createCallbackFcn(app, @SpectralflowButtonPushed, true);
            app.SpectralflowButton.Text = 'Spectral flow';
            app.WaveletsButton = uibutton(app.AnalysisPanel, 'push');
            app.WaveletsButton.ButtonPushedFcn = createCallbackFcn(app, @WaveletsButtonPushed, true);
            app.WaveletsButton.Text = 'Wavelets';
            app.ThermalanalysisButton = uibutton(app.AnalysisPanel, 'push');
            app.ThermalanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalanalysisButtonPushed, true);
            app.ThermalanalysisButton.Text = 'Thermal analysis';
            app.SpectralbandsButton = uibutton(app.AnalysisPanel, 'push');
            app.SpectralbandsButton.ButtonPushedFcn = createCallbackFcn(app, @SpectralbandsButtonPushed, true);
            app.SpectralbandsButton.Text = 'Spectral bands';
            app.lpassCheckBox = uicheckbox(app.AnalysisPanel);
            app.lpassCheckBox.Text = 'lpass';
            app.ArtefactsButton = uibutton(app.AnalysisPanel, 'push');
            app.ArtefactsButton.ButtonPushedFcn = createCallbackFcn(app, @ArtefactsButtonPushed, true);
            app.ArtefactsButton.Visible = 'off';
            app.ArtefactsButton.Text = 'Artefacts';
            app.ApplytoallCheckBox = uicheckbox(app.AnalysisPanel);
            app.ApplytoallCheckBox.Text = 'Apply to all';
            app.ApplytoallCheckBox.Value = true;
            app.DrawROIsPanel = uipanel(app.UIFigure);
            app.DrawROIsPanel.Title = 'Draw ROIs';
            app.AddROIsButton = uibutton(app.DrawROIsPanel, 'push');
            app.AddROIsButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIsButtonPushed, true);
            app.AddROIsButton.Text = 'Add ROIs';
            app.NumberofROIsEditFieldLabel = uilabel(app.DrawROIsPanel);
            app.NumberofROIsEditFieldLabel.Text = 'Number of ROIs';
            app.NumberofROIsEditField = uieditfield(app.DrawROIsPanel, 'numeric');
            app.NumberofROIsEditField.Value = 3;
            app.ROIsListBoxLabel = uilabel(app.DrawROIsPanel);
            app.ROIsListBoxLabel.Text = 'ROIs';
            app.ROIsListBox = uilistbox(app.DrawROIsPanel);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.Multiselect = 'on';
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {};
            app.BothimagesCheckBox = uicheckbox(app.DrawROIsPanel);
            app.BothimagesCheckBox.Visible = 'off';
            app.BothimagesCheckBox.Text = 'Both images';
            app.BothimagesCheckBox.Value = true;
            app.DualdrawCheckBox = uicheckbox(app.DrawROIsPanel);
            app.DualdrawCheckBox.Visible = 'off';
            app.DualdrawCheckBox.Text = 'Dual draw';
            app.DualdrawCheckBox.Value = true;
            app.SavematButton = uibutton(app.UIFigure, 'push');
            app.SavematButton.ButtonPushedFcn = createCallbackFcn(app, @SavematButtonPushed, true);
            app.SavematButton.Text = 'Save .mat';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Value = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end